classdef View < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        gui                             matlab.ui.Figure
        HelpButton                      matlab.ui.control.Button
        SelectFile_2                    matlab.ui.container.Panel
        SelectDirectoryBtn              matlab.ui.control.Button
        OutputDirectory                 matlab.ui.control.EditField
        SettingsPanel                   matlab.ui.container.Panel
        DetectDuplicates                matlab.ui.control.CheckBox
        TableindexfieldLabel            matlab.ui.control.Label
        GenerateSummaryFile             matlab.ui.control.CheckBox
        ProvidernameEditFieldLabel      matlab.ui.control.Label
        ResponsiblepersonnameemailEditFieldLabel  matlab.ui.control.Label
        SortbillsbyfieldLabel           matlab.ui.control.Label
        FieldExampleText                matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        DataexampleLabel                matlab.ui.control.Label
        SplitbillsbyfieldDropDownLabel  matlab.ui.control.Label
        ExternalProjectMarker           matlab.ui.control.EditField
        DetectExternalProjects          matlab.ui.control.CheckBox
        CollaborationMarker             matlab.ui.control.EditField
        DetectCollaborations            matlab.ui.control.CheckBox
        ResponsiblePerson               matlab.ui.control.EditField
        ProviderName                    matlab.ui.control.EditField
        SortBillsField                  matlab.ui.control.DropDown
        SplitBillsField                 matlab.ui.control.DropDown
        TableIndexField                 matlab.ui.control.DropDown
        StartProcessingButton           matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        SelectFile                      matlab.ui.container.Panel
        DatastartingcellEditFieldLabel  matlab.ui.control.Label
        HeaderstartingcellEditFieldLabel  matlab.ui.control.Label
        SelectFileBtn                   matlab.ui.control.Button
        DataStartingCell                matlab.ui.control.EditField
        HeaderStartingCell              matlab.ui.control.EditField
        InputFilename                   matlab.ui.control.EditField
    end

    
    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = public)
        function results = publicFunctionTest(app)
            disp('public function test')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
        end

        % Callback function: CloseButton, gui
        function guiCloseRequest(app, event)
            app.winController.closeWindow();
            delete(app)
        end

        % Button pushed function: SelectFileBtn
        function SelectFileBtn_Callback(app, event)
            path = app.InputFilename.Value;
            [file, path] = uigetfile({'*.xls;*.xlsx','Excel files (*.xls, *.xlsx)'; ...
                                     '*.*',  'All Files (*.*)'},...
                                      'Select Excel file', path);
            if file~=0
                filename = fullfile(path, file);
                app.InputFilename.Value = filename;
                app.InputFilenameValueChanged(event);
            end
        end

        % Value changed function: InputFilename
        function InputFilenameValueChanged(app, event)
            value = app.InputFilename.Value;
            app.winController.updateFilename(value);
        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            app.winController.StartProcessing();
        end

        % Value changed function: CollaborationMarker, 
        % DataStartingCell, DetectCollaborations, DetectDuplicates, 
        % DetectExternalProjects, ExternalProjectMarker, 
        % GenerateSummaryFile, HeaderStartingCell, ProviderName, 
        % ResponsiblePerson, SortBillsField, SplitBillsField, 
        % TableIndexField
        function updateSettings(app, event)
            app.winController.updateSettings(event);

        end

        % Button pushed function: SelectDirectoryBtn
        function SelectDirectoryBtnButtonPushed(app, event)
            selpath = uigetdir(app.OutputDirectory.Value, 'Select output directory');
            if isempty(selpath); return; end
            app.OutputDirectory.Value = selpath;
            app.OutputDirectoryValueChanged(event);
        end

        % Value changed function: OutputDirectory
        function OutputDirectoryValueChanged(app, event)
            value = app.OutputDirectory.Value;
            app.winController.updateDirectory(value);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web('https://github.com/Ajaxels/OpenIris-SplitTheBill/wiki', '-helpbrowser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.gui = uifigure('Visible', 'off');
            app.gui.Name = 'OpenIris Split the Bill';
            app.gui.CloseRequestFcn = createCallbackFcn(app, @guiCloseRequest, true);
            app.SelectFile = uipanel(app.gui);
            app.SelectFile.Title = 'Select a file...';
            app.InputFilename = uieditfield(app.SelectFile, 'text');
            app.InputFilename.ValueChangedFcn = createCallbackFcn(app, @InputFilenameValueChanged, true);
            app.InputFilename.Tooltip = {'path to thhe invoice Excel file'};
            app.HeaderStartingCell = uieditfield(app.SelectFile, 'text');
            app.HeaderStartingCell.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.HeaderStartingCell.Tooltip = {'Index of the 1st header row cell'};
            app.HeaderStartingCell.Value = 'A3';
            app.DataStartingCell = uieditfield(app.SelectFile, 'text');
            app.DataStartingCell.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.DataStartingCell.Tooltip = {'Index of the 1st cell containing charges'};
            app.DataStartingCell.Value = 'A4';
            app.SelectFileBtn = uibutton(app.SelectFile, 'push');
            app.SelectFileBtn.ButtonPushedFcn = createCallbackFcn(app, @SelectFileBtn_Callback, true);
            app.SelectFileBtn.Tooltip = {'press to select Excel file with invoice information'; ''; ''};
            app.SelectFileBtn.Text = '...';
            app.HeaderstartingcellEditFieldLabel = uilabel(app.SelectFile);
            app.HeaderstartingcellEditFieldLabel.Text = 'Header starting cell';
            app.DatastartingcellEditFieldLabel = uilabel(app.SelectFile);
            app.DatastartingcellEditFieldLabel.Text = 'Data starting cell';
            app.CloseButton = uibutton(app.gui, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @guiCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.StartProcessingButton = uibutton(app.gui, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Text = 'Start processing';
            app.SettingsPanel = uipanel(app.gui);
            app.SettingsPanel.Title = 'Settings';
            app.TableIndexField = uidropdown(app.SettingsPanel);
            app.TableIndexField.Items = {};
            app.TableIndexField.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.TableIndexField.Tooltip = {'Define name of the field that contains indices of charges (normally ID) for detection of duplicates'};
            app.TableIndexField.Value = {};
            app.SplitBillsField = uidropdown(app.SettingsPanel);
            app.SplitBillsField.Items = {};
            app.SplitBillsField.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.SplitBillsField.Tooltip = {'Define name of the field to use for splitting the charges into individual Excel invoices (RequestID)'};
            app.SplitBillsField.Value = {};
            app.SortBillsField = uidropdown(app.SettingsPanel);
            app.SortBillsField.Items = {};
            app.SortBillsField.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.SortBillsField.Tooltip = {'Define name of the field to be used for sorting the charges'};
            app.SortBillsField.Value = {};
            app.ProviderName = uieditfield(app.SettingsPanel, 'text');
            app.ProviderName.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.ProviderName.Tooltip = {'Name of the provider for the header of the generated invoices'};
            app.ResponsiblePerson = uieditfield(app.SettingsPanel, 'text');
            app.ResponsiblePerson.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.ResponsiblePerson.Tooltip = {'Name of the responsible person for the header of the generated invoices'};
            app.DetectCollaborations = uicheckbox(app.SettingsPanel);
            app.DetectCollaborations.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.DetectCollaborations.Tooltip = {'detect collaboration project and move them to a separate folder'};
            app.DetectCollaborations.Text = 'Detect collaborations';
            app.CollaborationMarker = uieditfield(app.SettingsPanel, 'text');
            app.CollaborationMarker.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.CollaborationMarker.Tooltip = {'the project assigned to collaborations, when this marker is found in the project title field'};
            app.CollaborationMarker.Value = '[C]';
            app.DetectExternalProjects = uicheckbox(app.SettingsPanel);
            app.DetectExternalProjects.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.DetectExternalProjects.Tooltip = {'detect collaboration project and move them to a separate folder'};
            app.DetectExternalProjects.Text = 'Detect external projects';
            app.ExternalProjectMarker = uieditfield(app.SettingsPanel, 'text');
            app.ExternalProjectMarker.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.ExternalProjectMarker.Tooltip = {'the project assigned to external projects, when this marker is found in the project title field'};
            app.ExternalProjectMarker.Value = '[E]';
            app.SplitbillsbyfieldDropDownLabel = uilabel(app.SettingsPanel);
            app.SplitbillsbyfieldDropDownLabel.Text = 'Split bills by field:';
            app.DataexampleLabel = uilabel(app.SettingsPanel);
            app.DataexampleLabel.Text = 'Data example:';
            app.UITable = uitable(app.SettingsPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.FieldExampleText = uilabel(app.SettingsPanel);
            app.SortbillsbyfieldLabel = uilabel(app.SettingsPanel);
            app.SortbillsbyfieldLabel.Text = 'Sort bills by field:';
            app.ResponsiblepersonnameemailEditFieldLabel = uilabel(app.SettingsPanel);
            app.ResponsiblepersonnameemailEditFieldLabel.Text = 'Responsible person (name, email):';
            app.ProvidernameEditFieldLabel = uilabel(app.SettingsPanel);
            app.ProvidernameEditFieldLabel.Text = 'Provider name:';
            app.GenerateSummaryFile = uicheckbox(app.SettingsPanel);
            app.GenerateSummaryFile.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.GenerateSummaryFile.Tooltip = {'Generate a summary file with total charges'};
            app.GenerateSummaryFile.Text = 'Generate summary file';
            app.GenerateSummaryFile.Value = true;
            app.TableindexfieldLabel = uilabel(app.SettingsPanel);
            app.TableindexfieldLabel.Text = 'Table index field:';
            app.DetectDuplicates = uicheckbox(app.SettingsPanel);
            app.DetectDuplicates.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.DetectDuplicates.Tooltip = {'Detect duplicates in the table, each charge should have an unique index, all duplicates will be detected'};
            app.DetectDuplicates.Text = 'Detect duplicates';
            app.DetectDuplicates.Value = true;
            app.SelectFile_2 = uipanel(app.gui);
            app.SelectFile_2.Title = 'Output directory';
            app.OutputDirectory = uieditfield(app.SelectFile_2, 'text');
            app.OutputDirectory.ValueChangedFcn = createCallbackFcn(app, @OutputDirectoryValueChanged, true);
            app.OutputDirectory.Tooltip = {'Define the output directory for results'; ''};
            app.SelectDirectoryBtn = uibutton(app.SelectFile_2, 'push');
            app.SelectDirectoryBtn.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryBtnButtonPushed, true);
            app.SelectDirectoryBtn.Tooltip = {'Define the output directory for results'};
            app.SelectDirectoryBtn.Text = '...';
            app.HelpButton = uibutton(app.gui, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.gui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = View(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.gui)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.gui)
        end
    end
end